classdef BAM_Online_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ValidTrialGauge                 matlab.ui.control.LinearGauge
        ValidTrialGaugeLabel            matlab.ui.control.Label
        CYSpinner                       matlab.ui.control.Spinner
        CXSpinner_2Label_2              matlab.ui.control.Label
        CXSpinner                       matlab.ui.control.Spinner
        CXSpinner_2Label                matlab.ui.control.Label
        MemoryUseage                    matlab.ui.control.SemicircularGauge
        SessionTimeLabel                matlab.ui.control.Label
        ThresholdSpinner                matlab.ui.control.Spinner
        ThresholdSpinnerLabel           matlab.ui.control.Label
        SelectSession                   matlab.ui.control.DropDown
        InterestedSessionDropDownLabel  matlab.ui.control.Label
        BAMOnlineAnalysisSystemLabel    matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        EyeSummary                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            global BAM_config BAM_analysis
            BAM_config = load('default_params.mat').BAM_config;
            BAM_config.root_dir = fN_localize_app();
            BAM_analysis = struct;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global BAM_config BAM_analysis 

            % copy fake data
            debug_mode = 1;
            BAM_config.today_name = strrep(datestr(datetime), ' ', '_');
            BAM_config.today_name = BAM_config.today_name(1:11);
            if(debug_mode)
                BAM_config.today_name = '24-Feb-2023';
                rmdir 'C:\Users\DELL\Documents\GitHub\BAM_online_analysis\data\*' s
                copyfile('C:\Users\DELL\Desktop\data', 'C:\Users\DELL\Documents\GitHub\BAM_online_analysis\data')
            end

            t0=GetSecs;
            while(1)
                        while(GetSecs-t0>BAM_config.combine_interval)

                            [BAM_config, BAM_analysis, app]=fN_combine_mat(BAM_config, BAM_analysis, app);

                            [BAM_config, BAM_analysis, app]=fN_plot_stats(BAM_config, BAM_analysis, app);

                            t0=GetSecs;
                        end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.EyeSummary = uiaxes(app.UIFigure);
            title(app.EyeSummary, 'Eye Location Log Prob')
            zlabel(app.EyeSummary, 'Z')
            app.EyeSummary.XTick = [];
            app.EyeSummary.YTick = [];
            app.EyeSummary.YTickLabel = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Blab_logo.png';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.BAMOnlineAnalysisSystemLabel = uilabel(app.UIFigure);
            app.BAMOnlineAnalysisSystemLabel.Text = 'BAM Online Analysis System';
            app.InterestedSessionDropDownLabel = uilabel(app.UIFigure);
            app.InterestedSessionDropDownLabel.Text = 'Interested Session';
            app.SelectSession = uidropdown(app.UIFigure);
            app.ThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.UIFigure);
            app.ThresholdSpinner.Value = 2;
            app.SessionTimeLabel = uilabel(app.UIFigure);
            app.SessionTimeLabel.Text = 'Session Time';
            app.MemoryUseage = uigauge(app.UIFigure, 'semicircular');
            app.CXSpinner_2Label = uilabel(app.UIFigure);
            app.CXSpinner_2Label.Text = 'CX';
            app.CXSpinner = uispinner(app.UIFigure);
            app.CXSpinner.Step = 0.1;
            app.CXSpinner_2Label_2 = uilabel(app.UIFigure);
            app.CXSpinner_2Label_2.Text = 'CY';
            app.CYSpinner = uispinner(app.UIFigure);
            app.CYSpinner.Step = 0.1;
            app.ValidTrialGaugeLabel = uilabel(app.UIFigure);
            app.ValidTrialGaugeLabel.Text = 'Valid Trial';
            app.ValidTrialGauge = uigauge(app.UIFigure, 'linear');
            app.ValidTrialGauge.Limits = [0 120];
            app.ValidTrialGauge.MajorTicks = [0 60 120];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAM_Online_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end